classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        TransmitterDataIntervalmsLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        Image3                          matlab.ui.control.Image
        DetectedImageLabel              matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        CameraFrameIntervalmsLabel      matlab.ui.control.Label
        FrameIntervalLabel              matlab.ui.control.Label
        GridLayout11                    matlab.ui.container.GridLayout
        Image2                          matlab.ui.control.Image
        ProcessedImageLabel             matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        TextArea_2                      matlab.ui.control.TextArea
        TxDataLabel                     matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        RxDataLabel                     matlab.ui.control.Label
        AlgorithmsButtonGroup           matlab.ui.container.ButtonGroup
        LinearButton                    matlab.ui.control.RadioButton
        BinaryL1Button                  matlab.ui.control.RadioButton
        BinaryL2Button                  matlab.ui.control.RadioButton
        GridLayout12                    matlab.ui.container.GridLayout
        Label_2                         matlab.ui.control.Label
        GridLayout13                    matlab.ui.container.GridLayout
        ElapsedTimeLabel                matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        cam=0; % Description
    end   
    
    properties (Access = public)
        stopFlag=0 % Description 
        comport=0;
        %t = timer('TimerFcn',@TimerFun, 'ExecutionMode',...
    %'fixedSpacing','Period',10,'StartDelay',5);

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopFlag=1;
            %stop(t);
            delete (app.cam);
            clear cam;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            clc;
            %start(t);
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            %app.comport=serial('COM3','BaudRate',9600); % Description
            %app.comport.ReadAsyncMode = 'manual';
            %fopen(app.comport);
            allStop=[];
            app.stopFlag=0;
            stopper=0;
            prevTxRate=-1;
            currTxRate=-1;
            txRate=-1;
            %finTxRate=[];
            %finBER=[];
            eR=0;
            eRs=[];
            txLen=0;
            txLens=[];
            txData="";
                while txRate==-1
                     [txRate,txData]=getTxRate();
                    disp("Finding TxRate"+txRate);
                end
                txData=string(txData);
                txData=convertStringsToChars(txData);
                [~,loopVal]=size(txData);
                disp("LoopVal:"+loopVal);
                loopVal=(loopVal*3)*10*3;
                app.Label.Text=""+txRate;
                app.TextArea_2.Value=txData;
                frameRate=txRate/3;
                app.FrameIntervalLabel.Text=""+frameRate;
                app.cam=setCamParameter();
                allChar=[];
                recoveredClock=frameRate/1000;
                flag=1;
                foundAt=-1;
                %count=0;
                charArray=[];
                s='';
                name = app.AlgorithmsButtonGroup.SelectedObject;
                if(isequal(name.Text,"Linear"))
                    value=8;
                elseif(isequal(name.Text,"BinaryL1"))
                    value=4;
                elseif(isequal(name.Text,"BinaryL2"))
                    value=2;
                end
                disp("Algo Selection");
                
                
                fromSet=generateMatrices(value);
            
                for i=1:loopVal
                    if app.stopFlag==1
                        delete (app.cam);
                        clear cam;
                        %fclose(app.comport);
                        break;
                    end
                    img = snapshot(app.cam);
                    img=imcrop(img,[58 20 78 80]);
                    app.Image3.ImageSource=img;
                    %imshow(img);
                    img=rgb2gray(img);
                    %img=imbinarize(img);
                    img=imresize(img,[8 8]);
                    img=imbinarize(img);
                    J1 = 255 * repmat(uint8(img), 1, 1, 3);
                    app.Image2.ImageSource=J1;
                    tic;
                   switch value
                        case 8
                            [~,foundAt]=searchMatrix(fromSet,img);
                        case 4
                            [~,foundAt]=parallelSearchMatrix1(fromSet,img);
                        case 2
                            [~,foundAt]=parallelSearchMatrix2(fromSet,img);
                   end
                   stop=toc;
                   allStop=[allStop stop];
                   %plot(app.UIAxes,[min(allStop) mean(allStop) max(allStop)]);
                   disp(foundAt);
                   if foundAt~=-1
                        charDec=bin2dec(foundAt);
                        allChar=[allChar charDec];
    %                     s=strcat(s,char(charDec));
    %                     disp(s);
    %                     app.TextArea.Value=s;
                        %scroll(app.TextArea, 'bottom');      
           
                        %disp(char(charDec));
                        if(charDec==60)
                            charArray=[];
                            flag=1;
                        elseif(charDec==62 && flag==1)
                            m=mode(charArray);
                            s=strcat(s,char(m));
                            disp(s);
                            if (m=='H')
                                [eR, txLen]=getBER(txData,s);
                                eRs=[eRs eR];
                                txLens=[txLens txLen*8];                           
                                plot(app.UIAxes2,eRs,txLens);
                            end
                            app.TextArea.Value=s;
                            flag=0;
                        elseif flag==1
                            charArray=[charArray charDec];
                        end
                    end
                    %imshow(imresize(img,[64 64]));
                    %pause(1);
                    disp("i"+i);
                    pause(recoveredClock);  
                end
                
                %finTxRate=[finTxRate 1000/txRate];
                %finBER=[finBER sBER];
                %disp("BER:"+sBER);
                
            delete (app.cam);
            clear cam;
            %fclose(app.comport);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StartButton = uibutton(app.GridLayout2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.TransmitterDataIntervalmsLabel = uilabel(app.GridLayout8);
            app.TransmitterDataIntervalmsLabel.Text = {'Transmitter Data Interval'; '(ms) '};
            app.Label = uilabel(app.GridLayout8);
            app.Label.Text = '';
            app.GridLayout10 = uigridlayout(app.GridLayout3);
            app.Image3 = uiimage(app.GridLayout10);
            app.DetectedImageLabel = uilabel(app.GridLayout10);
            app.DetectedImageLabel.Text = 'Detected Image';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.CameraFrameIntervalmsLabel = uilabel(app.GridLayout9);
            app.CameraFrameIntervalmsLabel.Text = {'Camera Frame Interval'; '(ms)'};
            app.FrameIntervalLabel = uilabel(app.GridLayout9);
            app.FrameIntervalLabel.Text = '';
            app.GridLayout11 = uigridlayout(app.GridLayout4);
            app.Image2 = uiimage(app.GridLayout11);
            app.ProcessedImageLabel = uilabel(app.GridLayout11);
            app.ProcessedImageLabel.Text = 'Processed Image';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.TextArea_2 = uitextarea(app.GridLayout6);
            app.TxDataLabel = uilabel(app.GridLayout6);
            app.TxDataLabel.Text = 'Tx Data';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.TextArea = uitextarea(app.GridLayout7);
            app.RxDataLabel = uilabel(app.GridLayout7);
            app.RxDataLabel.Text = 'Rx Data';
            app.AlgorithmsButtonGroup = uibuttongroup(app.GridLayout);
            app.AlgorithmsButtonGroup.Title = 'Algorithms:';
            app.LinearButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.BinaryL1Button = uiradiobutton(app.AlgorithmsButtonGroup);
            app.BinaryL1Button.Text = 'BinaryL1';
            app.BinaryL2Button = uiradiobutton(app.AlgorithmsButtonGroup);
            app.BinaryL2Button.Text = 'BinaryL2';
            app.GridLayout12 = uigridlayout(app.GridLayout);
            app.Label_2 = uilabel(app.GridLayout12);
            app.Label_2.Text = '';
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.ElapsedTimeLabel = uilabel(app.GridLayout13);
            app.ElapsedTimeLabel.Text = 'Elapsed Time:';
            app.secLabel = uilabel(app.GridLayout13);
            app.secLabel.Text = '31.23 sec';
            app.UIAxes2 = uiaxes(app.GridLayout12);
            title(app.UIAxes2, 'BER')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end